classdef MainMenuApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        OptionsLabel            matlab.ui.control.Label
        RunStrokeRehabilitationDSSButton  matlab.ui.control.Button
        CheckExerciseButton     matlab.ui.control.Button
        SelectwhichexerciseyouwanttoreviewButtonGroup  matlab.ui.container.ButtonGroup
        BacktoMenuButton        matlab.ui.control.Button
        RotateWristButton       matlab.ui.control.Button
        ReachandRetrieveButton  matlab.ui.control.Button
        HorizontalArmButton     matlab.ui.control.Button
        CuptoMouthButton_2      matlab.ui.control.Button
        Image9                  matlab.ui.control.Image
        Image8                  matlab.ui.control.Image
        Image7                  matlab.ui.control.Image
        Image6                  matlab.ui.control.Image
        PushToStartButton       matlab.ui.control.Button
        Image3                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function resetApp(app) %Back to Menu Screen RESET
            hideAllComponents(app);
            app.SelectwhichexerciseyouwanttoreviewButtonGroup.Visible = 'off';
            % Show title screen
            app.Image2.Visible = 'on';
            app.Image.Visible = 'on';
            app.Image3.Visible = 'on';
            app.PushToStartButton.Visible = 'on';
            app.UIFigure.Color = [0 0 0];     
        end
     %Back to Menu Screen RESET
        
     function hideAllComponents(app)

    components = { ...
        app.RotateWristButton, app.ReachandRetrieveButton, ...
        app.HorizontalArmButton, app.CuptoMouthButton_2, ...
        app.Image9, app.Image8, app.Image7, app.Image6, ...
        app.PushToStartButton, app.Image3, app.Image, app.Image2, ...
        app.OptionsLabel, app.CheckExerciseButton, app.RunStrokeRehabilitationDSSButton};

    for i = 1:length(components)
        components{i}.Visible = 'off';   % 🔥 use {} not ()
    end

     end    
%HIDE ALL


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PushToStartButton
        function PushToStartButtonPushed(app, event)
        hideAllComponents(app);
        app.UIFigure.Color = [1.0, 1.0, 1.0];
        app.OptionsLabel.Visible = 'on';
        app.RunStrokeRehabilitationDSSButton.Visible = 'on';
        app.CheckExerciseButton.Visible = 'on';
        end

        % Button pushed function: RotateWristButton
        function RotateWristButtonPushed(app, event)
        
        hideAllComponents(app);
        
        app.BacktoMenuButton.Visible = 'on';
        app.SelectwhichexerciseyouwanttoreviewButtonGroup.Title = ' ';

        
        end

        % Button pushed function: HorizontalArmButton
        function HorizontalArmButtonPushed(app, event)
        
        hideAllComponents(app);
        
        app.BacktoMenuButton.Visible = 'on';
        app.SelectwhichexerciseyouwanttoreviewButtonGroup.Title = ' ';
        end

        % Button pushed function: CuptoMouthButton_2
        function CuptoMouthButton_2Pushed(app, event)
        
        hideAllComponents(app);
        
        app.BacktoMenuButton.Visible = 'on';
        app.SelectwhichexerciseyouwanttoreviewButtonGroup.Title = ' ';
        end

        % Button pushed function: BacktoMenuButton
        function BacktoMenuButtonPushed(app, event)
        
        resetApp(app);    
           
        end

        % Button pushed function: ReachandRetrieveButton
        function ReachandRetrieveButtonPushed2(app, event)
        hideAllComponents(app);
        
        app.BacktoMenuButton.Visible = 'on'; 
        app.SelectwhichexerciseyouwanttoreviewButtonGroup.Title = ' ';
        end

        % Button pushed function: RunStrokeRehabilitationDSSButton
        function RunStrokeRehabilitationDSSButtonPushed(app, event)
        StrokeRehabDSS;
            delete(app);
        end

        % Button pushed function: CheckExerciseButton
        function CheckExerciseButtonPushed(app, event)
        hideAllComponents(app);
        app.UIFigure.Color = [1 1 1];

        app.SelectwhichexerciseyouwanttoreviewButtonGroup.Visible = 'on';
        app.RotateWristButton.Visible = 'on';
        app.CuptoMouthButton_2.Visible = 'on';
        app.HorizontalArmButton.Visible = 'on';
        app.ReachandRetrieveButton.Visible = 'on';
        app.Image6.Visible = 'on';
        app.Image7.Visible = 'on';
        app.Image8.Visible = 'on';
        app.Image9.Visible = 'on';
        app.BacktoMenuButton.Visible = 'on';

        app.SelectwhichexerciseyouwanttoreviewButtonGroup.Title = 'Select which exercise you want to review';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'titlepagebackground.jpg');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'titlepageimage.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'titlefont.png');
            app.PushToStartButton = uibutton(app.UIFigure, 'push');
            app.PushToStartButton.ButtonPushedFcn = createCallbackFcn(app, @PushToStartButtonPushed, true);
            app.PushToStartButton.Text = 'Push To Start';
            app.SelectwhichexerciseyouwanttoreviewButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectwhichexerciseyouwanttoreviewButtonGroup.Title = 'Select which exercise you want to review.';
            app.SelectwhichexerciseyouwanttoreviewButtonGroup.Visible = 'off';
            app.Image6 = uiimage(app.SelectwhichexerciseyouwanttoreviewButtonGroup);
            app.Image6.Visible = 'off';
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'reach and retrieve.jpeg');
            app.Image7 = uiimage(app.SelectwhichexerciseyouwanttoreviewButtonGroup);
            app.Image7.Visible = 'off';
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'rotate wrist.jpg');
            app.Image8 = uiimage(app.SelectwhichexerciseyouwanttoreviewButtonGroup);
            app.Image8.Visible = 'off';
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'horizontal arm.jpeg');
            app.Image9 = uiimage(app.SelectwhichexerciseyouwanttoreviewButtonGroup);
            app.Image9.Visible = 'off';
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'cup to mouth.jpeg');
            app.CuptoMouthButton_2 = uibutton(app.SelectwhichexerciseyouwanttoreviewButtonGroup, 'push');
            app.CuptoMouthButton_2.ButtonPushedFcn = createCallbackFcn(app, @CuptoMouthButton_2Pushed, true);
            app.CuptoMouthButton_2.Visible = 'off';
            app.CuptoMouthButton_2.Text = 'Cup to Mouth';
            app.HorizontalArmButton = uibutton(app.SelectwhichexerciseyouwanttoreviewButtonGroup, 'push');
            app.HorizontalArmButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalArmButtonPushed, true);
            app.HorizontalArmButton.Visible = 'off';
            app.HorizontalArmButton.Text = 'Horizontal Arm';
            app.ReachandRetrieveButton = uibutton(app.SelectwhichexerciseyouwanttoreviewButtonGroup, 'push');
            app.ReachandRetrieveButton.ButtonPushedFcn = createCallbackFcn(app, @ReachandRetrieveButtonPushed2, true);
            app.ReachandRetrieveButton.Visible = 'off';
            app.ReachandRetrieveButton.Text = 'Reach and Retrieve';
            app.RotateWristButton = uibutton(app.SelectwhichexerciseyouwanttoreviewButtonGroup, 'push');
            app.RotateWristButton.ButtonPushedFcn = createCallbackFcn(app, @RotateWristButtonPushed, true);
            app.RotateWristButton.Visible = 'off';
            app.RotateWristButton.Text = 'Rotate Wrist';
            app.BacktoMenuButton = uibutton(app.SelectwhichexerciseyouwanttoreviewButtonGroup, 'push');
            app.BacktoMenuButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoMenuButtonPushed, true);
            app.BacktoMenuButton.Visible = 'off';
            app.BacktoMenuButton.Text = 'Back to Menu';
            app.CheckExerciseButton = uibutton(app.UIFigure, 'push');
            app.CheckExerciseButton.ButtonPushedFcn = createCallbackFcn(app, @CheckExerciseButtonPushed, true);
            app.CheckExerciseButton.Visible = 'off';
            app.CheckExerciseButton.Text = 'Check Exercise';
            app.RunStrokeRehabilitationDSSButton = uibutton(app.UIFigure, 'push');
            app.RunStrokeRehabilitationDSSButton.ButtonPushedFcn = createCallbackFcn(app, @RunStrokeRehabilitationDSSButtonPushed, true);
            app.RunStrokeRehabilitationDSSButton.Visible = 'off';
            app.RunStrokeRehabilitationDSSButton.Text = 'Run Stroke Rehabilitation DSS';
            app.OptionsLabel = uilabel(app.UIFigure);
            app.OptionsLabel.Visible = 'off';
            app.OptionsLabel.Text = 'Options';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainMenuApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end