classdef MainMenuApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BacktoMenuButton               matlab.ui.control.Button
        Image7                         matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
        ReachandRetrieveButton         matlab.ui.control.Button
        CuptoMouthButton_2             matlab.ui.control.Button
        HorizontalArmButton            matlab.ui.control.Button
        Image8                         matlab.ui.control.Image
        Image9                         matlab.ui.control.Image
        RotateWristButton              matlab.ui.control.Button
        AddPatientButton               matlab.ui.control.Button
        EnterthePatientsNameEditField  matlab.ui.control.EditField
        EnterthePatientsNameEditFieldLabel  matlab.ui.control.Label
        PatientDropdown                matlab.ui.control.DropDown
        PatientDropdownDropDownLabel   matlab.ui.control.Label
        PleaseSelecttheFollowingPatientyouwouldliketoviewLabel  matlab.ui.control.Label
        OptionsLabel                   matlab.ui.control.Label
        RunStrokeRehabilitationDSSButton  matlab.ui.control.Button
        CheckExerciseButton            matlab.ui.control.Button
        PushToStartButton              matlab.ui.control.Button
        Image3                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
    end

    
    properties (Access = private)
       patientNames string = ["Patient 1","Patient 2","Patient 3","Patient 4"]
       selectedPatient string = ""
       selectedExercise string = ""
    end
    
    methods (Access = private)
        
        function resetApp(app) %Back to Menu Screen RESET
            hideAllComponents(app);
            
            % Show title screen
            app.Image2.Visible = 'on';
            app.Image.Visible = 'on';
            app.Image3.Visible = 'on';
            app.PushToStartButton.Visible = 'on';
            app.UIFigure.Color = [0 0 0];     
        end
     %Back to Menu Screen RESET

function openLatestSavedResult(app)

    if strlength(app.selectedPatient) == 0 || strlength(app.selectedExercise) == 0
        uialert(app.UIFigure, 'Select a patient and exercise first.', 'Missing Selection');
        return;
    end

    baseDir = fullfile(fileparts(mfilename('fullpath')), ...
        "PatientResults", char(app.selectedPatient), char(app.selectedExercise));

    if ~exist(baseDir, 'dir')
        uialert(app.UIFigure, ...
            sprintf('No saved data found for %s / %s.', app.selectedPatient, app.selectedExercise), ...
            'No Saved Data');
        return;
    end

    % --- Find latest images ---
    accFiles  = dir(fullfile(baseDir, "RawAcc_*.png"));
    gyroFiles = dir(fullfile(baseDir, "RawGyro_*.png"));
    resFiles  = dir(fullfile(baseDir, "Resultant_*.png"));

    if isempty(accFiles) || isempty(gyroFiles) || isempty(resFiles)
        uialert(app.UIFigure, 'Saved images not found in folder.', 'No Images');
        return;
    end

    % Get latest (by date)
    [~, i1] = max([accFiles.datenum]);
    [~, i2] = max([gyroFiles.datenum]);
    [~, i3] = max([resFiles.datenum]);

    accPath  = fullfile(baseDir, accFiles(i1).name);
    gyroPath = fullfile(baseDir, gyroFiles(i2).name);
    resPath  = fullfile(baseDir, resFiles(i3).name);

    % --- Open images ---
    figure('Name','Raw Acceleration');
    imshow(imread(accPath));

    figure('Name','Raw Gyroscope');
    imshow(imread(gyroPath));

    figure('Name','Resultant Signal');
    imshow(imread(resPath));

end
        
     function hideAllComponents(app)

    components = { ...
    app.RotateWristButton, app.ReachandRetrieveButton, ...
    app.HorizontalArmButton, app.CuptoMouthButton_2, ...
    app.Image9, app.Image8, app.Image7, app.Image6, ...
    app.PushToStartButton, app.Image3, app.Image, app.Image2, ...
    app.OptionsLabel, app.CheckExerciseButton, app.RunStrokeRehabilitationDSSButton, ...
    app.PleaseSelecttheFollowingPatientyouwouldliketoviewLabel, ... 
    app.PatientDropdown, app.EnterthePatientsNameEditField, app.AddPatientButton, ...
    app.EnterthePatientsNameEditFieldLabel};

    for i = 1:length(components)
        components{i}.Visible = 'off';   % 🔥 use {} not ()
    end

     end    
%HIDE ALL


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PushToStartButton
        function PushToStartButtonPushed(app, event)
        hideAllComponents(app);
        app.UIFigure.Color = [1.0, 1.0, 1.0];
        app.OptionsLabel.Visible = 'on';
        app.RunStrokeRehabilitationDSSButton.Visible = 'on';
        app.CheckExerciseButton.Visible = 'on';
        end

        % Button pushed function: RotateWristButton
        function RotateWristButtonPushed(app, event)
        
        hideAllComponents(app);
        
        app.BacktoMenuButton.Visible = 'on';
        app.selectedExercise = "RotateWrist90";
        app.openLatestSavedResult();


        end

        % Button pushed function: HorizontalArmButton
        function HorizontalArmButtonPushed(app, event)
        
        hideAllComponents(app);
        
        app.BacktoMenuButton.Visible = 'on';
        app.selectedExercise = "HorizontalArm";
        app.openLatestSavedResult();
       
        end

        % Button pushed function: CuptoMouthButton_2
        function CuptoMouthButton_2Pushed(app, event)
        
        hideAllComponents(app);
        
        app.BacktoMenuButton.Visible = 'on';
        app.selectedExercise = "Cup2Mouth";
        app.openLatestSavedResult();
       
        end

        % Button pushed function: BacktoMenuButton
        function BacktoMenuButtonPushed(app, event)
        
        resetApp(app);    
           
        end

        % Button pushed function: ReachandRetrieveButton
        function ReachandRetrieveButtonPushed2(app, event)
        hideAllComponents(app);
        
        app.BacktoMenuButton.Visible = 'on';
        app.selectedExercise = "Reach_retrieve";
        app.openLatestSavedResult();
        
      
        end

        % Button pushed function: RunStrokeRehabilitationDSSButton
        function RunStrokeRehabilitationDSSButtonPushed(app, event)
        StrokeRehabDSS;
            delete(app);
        end

        % Button pushed function: CheckExerciseButton
        function CheckExerciseButtonPushed(app, event)
        hideAllComponents(app);
        app.UIFigure.Color = [1 1 1];

        app.PleaseSelecttheFollowingPatientyouwouldliketoviewLabel.Visible = 'on';
        app.PatientDropdown.Visible = 'on';
        app.PatientDropdown.Visible = 'on';
        app.EnterthePatientsNameEditField.Visible = 'on';
        app.EnterthePatientsNameEditFieldLabel.Visible = 'on';
        app.AddPatientButton.Visible = 'on';

        app.selectedPatient = string(app.PatientDropdown.Value);

        
        app.RotateWristButton.Visible = 'on';
        app.CuptoMouthButton_2.Visible = 'on';
        app.HorizontalArmButton.Visible = 'on';
        app.ReachandRetrieveButton.Visible = 'on';
        app.Image6.Visible = 'on';
        app.Image7.Visible = 'on';
        app.Image8.Visible = 'on';
        app.Image9.Visible = 'on';
        app.BacktoMenuButton.Visible = 'on';
       
        end

        % Value changed function: PatientDropdown
        function PatientDropdownValueChanged(app, event)
            app.selectedPatient = string(app.PatientDropdown.Value); 
        end

        % Button pushed function: AddPatientButton
        function AddPatientButtonPushed(app, event)
            newName = strtrim(string(app.EnterthePatientsNameEditField.Value));

            if newName == ""
                uialert(app.UIFigure, 'Enter a patient name first.', 'Missing Name');
            return
            end

        existing = string(app.PatientDropdown.Items);
        if ~ismember(newName, existing)
            app.PatientDropdown.Items{end+1} = char(newName);
        end

        app.PatientDropdown.Value = char(newName);
        app.selectedPatient = newName;
        app.EnterthePatientsNameEditField.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'titlepagebackground.jpg');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'titlepageimage.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'titlefont.png');
            app.PushToStartButton = uibutton(app.UIFigure, 'push');
            app.PushToStartButton.ButtonPushedFcn = createCallbackFcn(app, @PushToStartButtonPushed, true);
            app.PushToStartButton.Text = 'Push To Start';
            app.CheckExerciseButton = uibutton(app.UIFigure, 'push');
            app.CheckExerciseButton.ButtonPushedFcn = createCallbackFcn(app, @CheckExerciseButtonPushed, true);
            app.CheckExerciseButton.Visible = 'off';
            app.CheckExerciseButton.Text = 'Check Exercise';
            app.RunStrokeRehabilitationDSSButton = uibutton(app.UIFigure, 'push');
            app.RunStrokeRehabilitationDSSButton.ButtonPushedFcn = createCallbackFcn(app, @RunStrokeRehabilitationDSSButtonPushed, true);
            app.RunStrokeRehabilitationDSSButton.Visible = 'off';
            app.RunStrokeRehabilitationDSSButton.Text = 'Run Stroke Rehabilitation DSS';
            app.OptionsLabel = uilabel(app.UIFigure);
            app.OptionsLabel.Visible = 'off';
            app.OptionsLabel.Text = 'Options';
            app.PleaseSelecttheFollowingPatientyouwouldliketoviewLabel = uilabel(app.UIFigure);
            app.PleaseSelecttheFollowingPatientyouwouldliketoviewLabel.Visible = 'off';
            app.PleaseSelecttheFollowingPatientyouwouldliketoviewLabel.Text = 'Please Select the Following Patient you would like to view.';
            app.PatientDropdownDropDownLabel = uilabel(app.UIFigure);
            app.PatientDropdownDropDownLabel.Visible = 'off';
            app.PatientDropdownDropDownLabel.Text = 'Patient Dropdown';
            app.PatientDropdown = uidropdown(app.UIFigure);
            app.PatientDropdown.Items = {'Steve', 'Khaled', 'Hari', 'Sam'};
            app.PatientDropdown.ValueChangedFcn = createCallbackFcn(app, @PatientDropdownValueChanged, true);
            app.PatientDropdown.Visible = 'off';
            app.PatientDropdown.Value = 'Steve';
            app.EnterthePatientsNameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthePatientsNameEditFieldLabel.Visible = 'off';
            app.EnterthePatientsNameEditFieldLabel.Text = 'Enter the Patient''s Name';
            app.EnterthePatientsNameEditField = uieditfield(app.UIFigure, 'text');
            app.EnterthePatientsNameEditField.Visible = 'off';
            app.AddPatientButton = uibutton(app.UIFigure, 'push');
            app.AddPatientButton.ButtonPushedFcn = createCallbackFcn(app, @AddPatientButtonPushed, true);
            app.AddPatientButton.Visible = 'off';
            app.AddPatientButton.Text = 'Add Patient Button';
            app.RotateWristButton = uibutton(app.UIFigure, 'push');
            app.RotateWristButton.ButtonPushedFcn = createCallbackFcn(app, @RotateWristButtonPushed, true);
            app.RotateWristButton.Visible = 'off';
            app.RotateWristButton.Text = 'Rotate Wrist';
            app.Image9 = uiimage(app.UIFigure);
            app.Image9.Visible = 'off';
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'cup to mouth.jpeg');
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.Visible = 'off';
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'horizontal arm.jpeg');
            app.HorizontalArmButton = uibutton(app.UIFigure, 'push');
            app.HorizontalArmButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalArmButtonPushed, true);
            app.HorizontalArmButton.Visible = 'off';
            app.HorizontalArmButton.Text = 'Horizontal Arm';
            app.CuptoMouthButton_2 = uibutton(app.UIFigure, 'push');
            app.CuptoMouthButton_2.ButtonPushedFcn = createCallbackFcn(app, @CuptoMouthButton_2Pushed, true);
            app.CuptoMouthButton_2.Visible = 'off';
            app.CuptoMouthButton_2.Text = 'Cup to Mouth';
            app.ReachandRetrieveButton = uibutton(app.UIFigure, 'push');
            app.ReachandRetrieveButton.ButtonPushedFcn = createCallbackFcn(app, @ReachandRetrieveButtonPushed2, true);
            app.ReachandRetrieveButton.Visible = 'off';
            app.ReachandRetrieveButton.Text = 'Reach and Retrieve';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.Visible = 'off';
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'reach and retrieve.jpeg');
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.Visible = 'off';
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'rotate wrist.jpg');
            app.BacktoMenuButton = uibutton(app.UIFigure, 'push');
            app.BacktoMenuButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoMenuButtonPushed, true);
            app.BacktoMenuButton.Visible = 'off';
            app.BacktoMenuButton.Text = 'Back to Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainMenuApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end